classdef tms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrafficManagementSystemUIFigure  matlab.ui.Figure
        RESETButton                   matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        ResultLabel                   matlab.ui.control.Label
        SelectImagesButton            matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        CapturedImageLabel            matlab.ui.control.Label
        ReferrenceImageLabel          matlab.ui.control.Label
        TrafficManagementSystemLabel  matlab.ui.control.Label
        MatchButton                   matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        k;
        p;
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
           file=uigetfile({'*.jpg;*.png;'});
           if ~isequal(file,0)
               app.k=imread(file);
               app.Image.ImageSource=app.k;
           end
           file=uigetfile({'*.jpg;*.png;'});
           if ~isequal(file,0)
               app.p=imread(file);
               app.Image2.ImageSource=app.p;
           end
           app.MatchButton.Visible='on';
           app.SelectImagesButton.Visible='off';
        end

        % Button pushed function: MatchButton
        function MatchButtonPushed(app, event)
            app.MatchButton.Visible='off';
            app.Label_4.Visible='on';
            app.Label_4.Text='Matching...';
            [x,y,z]=size(app.k);
            if (z==1)
                ;
            else
                app.k=rgb2gray(app.k);
            end
            [x,y,z]=size(app.p);
            if(z==1)
                ;
            else
                app.p=rgb2gray(app.p);
            end
            
            k1=imresize(app.k,[300 300]);
            edge_det_k=edge(k1,'prewitt');
            
            p1=imresize(app.p,[300 300]);
            edge_det_p=edge(p1,'prewitt');
            
            matched_data=0;
            white_points=0;
            black_points=0;
            x=0;
            y=0;
            l=0;
            m=0;
            
            for a=1:1:300
                for b=1:1:300
                    if(edge_det_k(a,b)==1)
                        white_points=white_points+1;
                    else
                        black_points=black_points+1;
                    end
                end
            end
            for i=1:1:300
                for j=1:1:300
                    if(edge_det_k(i,j)==1)&(edge_det_p(i,j)==1)
                        matched_data=matched_data+1;
                    else
                        ;
                    end
                end
            end
            total_data=white_points;
            total_matched_percentage=(matched_data/total_data)*100;
            app.Label.Visible='on';
            app.Label_2.Visible='on';
            app.ResultLabel.Visible='on';
            app.Label_4.Text='Match Complete.';
            app.Label_3.Visible='on';
            app.Label_3.Text='PERCENTAGE MATCH';
            app.Label.Text=num2str(total_matched_percentage);
            if(total_matched_percentage>=30 & total_matched_percentage<60)
                app.Label_2.FontColor=[0.39 .83 .07];
                app.Label_2.Text='Green Light ON for 90 seconds';
            elseif(total_matched_percentage>=60 & total_matched_percentage<70)
                app.Label_2.FontColor=[0.39 .83 .07];
                app.Label_2.Text='Green Light ON for 60 seconds';
            elseif(total_matched_percentage>=70 & total_matched_percentage<90)
                app.Label_2.FontColor=[0.39 .83 .07];
                app.Label_2.Text='Green Light ON for 30 seconds';
            else
                app.Label_2.FontColor=[1 0 0];
                app.Label_2.Text='Red Light ON';
            end
            app.RESETButton.Visible='on';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Image.ImageSource='';
            app.Image2.ImageSource='';
            app.SelectImagesButton.Visible='on';
            app.Label_2.Visible='off';            
            app.Label.Visible='off';
            app.Label_3.Visible='off';
            app.Label_4.Visible='off';
            app.ResultLabel.Visible='off';
            app.RESETButton.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrafficManagementSystemUIFigure = uifigure('Visible', 'off');
            app.TrafficManagementSystemUIFigure.Name = 'Traffic Management System';
            app.TrafficManagementSystemUIFigure.Icon = 'index.png';
            app.Image = uiimage(app.TrafficManagementSystemUIFigure);
            app.Image2 = uiimage(app.TrafficManagementSystemUIFigure);
            app.MatchButton = uibutton(app.TrafficManagementSystemUIFigure, 'push');
            app.MatchButton.ButtonPushedFcn = createCallbackFcn(app, @MatchButtonPushed, true);
            app.MatchButton.Visible = 'off';
            app.MatchButton.Text = 'Match';
            app.TrafficManagementSystemLabel = uilabel(app.TrafficManagementSystemUIFigure);
            app.TrafficManagementSystemLabel.Text = 'Traffic Management System';
            app.ReferrenceImageLabel = uilabel(app.TrafficManagementSystemUIFigure);
            app.ReferrenceImageLabel.Text = 'Referrence Image';
            app.CapturedImageLabel = uilabel(app.TrafficManagementSystemUIFigure);
            app.CapturedImageLabel.Text = 'Captured Image';
            app.Label_3 = uilabel(app.TrafficManagementSystemUIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.Label = uilabel(app.TrafficManagementSystemUIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.SelectImagesButton = uibutton(app.TrafficManagementSystemUIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.ResultLabel = uilabel(app.TrafficManagementSystemUIFigure);
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'Result';
            app.Label_2 = uilabel(app.TrafficManagementSystemUIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.Label_4 = uilabel(app.TrafficManagementSystemUIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '';
            app.RESETButton = uibutton(app.TrafficManagementSystemUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Visible = 'off';
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.TrafficManagementSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tms

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrafficManagementSystemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrafficManagementSystemUIFigure)
        end
    end
end